classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        Image                         matlab.ui.control.Image
        ImportButton                  matlab.ui.control.Button
        FilenameEditField             matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
        Hyperparameter2DropDown       matlab.ui.control.DropDown
        Hyperparameter2DropDownLabel  matlab.ui.control.Label
        Hyperparameter4CheckBox       matlab.ui.control.CheckBox
        Hyperparameter3Switch         matlab.ui.control.Switch
        Hyperparameter3SwitchLabel    matlab.ui.control.Label
        Hyperparameter1Knob           matlab.ui.control.Knob
        Hyperparameter1KnobLabel      matlab.ui.control.Label
        ReconstructRoomButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        UserUpload; % Description
    end
    
    methods (Access = private)   
        % reads the files into a publicly accessible array
        function readImages(app, folderpath)   
            files = dir(fullfile(folderpath,'*jpg'));
            filename = {files.name};            
            filename = filename(~ismember(filename,{'.','..'}));
            images = cell([length(files) 1]);
            for i =1:length(files)                     
                images{i} = imread(append(folderpath,'/',filename{i}));    
            end
            app.UserUpload = images;
            app.Image.ImageSource = append(folderpath,'/',filename{1});
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            %value = app.FilenameEditField.Value; 
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            path = uigetdir('','Select folder');
            if(~path)
                % user canceled data import
                return
            end
            readImages(app, path);
            app.FilenameEditField.Value = path;  
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ReconstructRoomButton = uibutton(app.UIFigure, 'push');
            app.ReconstructRoomButton.Text = 'Reconstruct Room';
            app.Hyperparameter1KnobLabel = uilabel(app.UIFigure);
            app.Hyperparameter1KnobLabel.Text = 'Hyperparameter 1';
            app.Hyperparameter1Knob = uiknob(app.UIFigure, 'continuous');
            app.Hyperparameter3SwitchLabel = uilabel(app.UIFigure);
            app.Hyperparameter3SwitchLabel.Text = 'Hyperparameter 3';
            app.Hyperparameter3Switch = uiswitch(app.UIFigure, 'slider');
            app.Hyperparameter4CheckBox = uicheckbox(app.UIFigure);
            app.Hyperparameter4CheckBox.Text = 'Hyperparameter 4';
            app.Hyperparameter2DropDownLabel = uilabel(app.UIFigure);
            app.Hyperparameter2DropDownLabel.Text = 'Hyperparameter 2';
            app.Hyperparameter2DropDown = uidropdown(app.UIFigure);
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end